classdef NVE_MountEngine_App_960207v1_temp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        title                         matlab.ui.control.Label
        tab_design                    matlab.ui.container.TabGroup
        tab_IP                        matlab.ui.container.Tab
        LabelEditField                matlab.ui.control.Label
        edit_mass                     matlab.ui.control.EditField
        Label2                        matlab.ui.control.Label
        InertiaTensor                 matlab.ui.container.Panel
        edit_Ixx                      matlab.ui.control.EditField
        edit_Ixy                      matlab.ui.control.EditField
        edit_Ixz                      matlab.ui.control.EditField
        edit_Iyz                      matlab.ui.control.EditField
        edit_Iyy                      matlab.ui.control.EditField
        EditField7                    matlab.ui.control.EditField
        edit_Izz                      matlab.ui.control.EditField
        EditField9                    matlab.ui.control.EditField
        EditField10                   matlab.ui.control.EditField
        panel_mountingsystem          matlab.ui.container.Panel
        LabelSpinner                  matlab.ui.control.Label
        spinner_mountsnumber          matlab.ui.control.Spinner
        panel_mountstype              matlab.ui.container.Panel
        LabelDropDown2                matlab.ui.control.Label
        popup_mount1type              matlab.ui.control.DropDown
        Label4                        matlab.ui.control.Label
        popup_mount2type              matlab.ui.control.DropDown
        Label5                        matlab.ui.control.Label
        popup_mount3type              matlab.ui.control.DropDown
        Label6                        matlab.ui.control.Label
        popup_mount4type              matlab.ui.control.DropDown
        panel_mountsposition          matlab.ui.container.Panel
        LabelEditField11              matlab.ui.control.Label
        edit_mount1position           matlab.ui.control.EditField
        Label7                        matlab.ui.control.Label
        edit_mount2position           matlab.ui.control.EditField
        Label8                        matlab.ui.control.Label
        edit_mount3position           matlab.ui.control.EditField
        Label9                        matlab.ui.control.Label
        edit_mount4position           matlab.ui.control.EditField
        panel_mountsorientation       matlab.ui.container.Panel
        Label10                       matlab.ui.control.Label
        edit_mount1orientation        matlab.ui.control.EditField
        Label11                       matlab.ui.control.Label
        edit_mount2orientation        matlab.ui.control.EditField
        Label12                       matlab.ui.control.Label
        edit_mount3orientation        matlab.ui.control.EditField
        Label13                       matlab.ui.control.Label
        edit_mount4orientation        matlab.ui.control.EditField
        Label14                       matlab.ui.control.Label
        Label15                       matlab.ui.control.Label
        Label3                        matlab.ui.control.Label
        tab_MP                        matlab.ui.container.Tab
        panel_mount1e                 matlab.ui.container.Panel
        LabelEditField12              matlab.ui.control.Label
        edit_mount1stiff              matlab.ui.control.EditField
        Label17                       matlab.ui.control.Label
        Label16                       matlab.ui.control.Label
        edit_mount1damp               matlab.ui.control.EditField
        Label18                       matlab.ui.control.Label
        panel_mount2e                 matlab.ui.container.Panel
        Label19                       matlab.ui.control.Label
        edit_mount2stiff              matlab.ui.control.EditField
        Label20                       matlab.ui.control.Label
        Label21                       matlab.ui.control.Label
        edit_mount2damp               matlab.ui.control.EditField
        Label22                       matlab.ui.control.Label
        panel_mount3e                 matlab.ui.container.Panel
        Label23                       matlab.ui.control.Label
        edit_mount3stiff              matlab.ui.control.EditField
        Label24                       matlab.ui.control.Label
        Label25                       matlab.ui.control.Label
        edit_mount3damp               matlab.ui.control.EditField
        Label26                       matlab.ui.control.Label
        panel_mount4e                 matlab.ui.container.Panel
        Label27                       matlab.ui.control.Label
        edit_mount4stiff              matlab.ui.control.EditField
        Label28                       matlab.ui.control.Label
        Label29                       matlab.ui.control.Label
        edit_mount4damp               matlab.ui.control.EditField
        Label30                       matlab.ui.control.Label
        tab_EP                        matlab.ui.container.Tab
        Panel                         matlab.ui.container.Panel
        Label31                       matlab.ui.control.Label
        TorqueAmp                     matlab.ui.control.EditField
        Label32                       matlab.ui.control.Label
        Label33                       matlab.ui.control.Label
        IdleSpeed                     matlab.ui.control.EditField
        Label34                       matlab.ui.control.Label
        tab_DM                        matlab.ui.container.Tab
        bg_designstrategy             matlab.ui.container.ButtonGroup
        RadioButton                   matlab.ui.control.RadioButton
        RadioButton2                  matlab.ui.control.RadioButton
        RadioButton3                  matlab.ui.control.RadioButton
        RadioButton4                  matlab.ui.control.RadioButton
        RadioButton5                  matlab.ui.control.RadioButton
        RadioButton6                  matlab.ui.control.RadioButton
        RadioButton7                  matlab.ui.control.RadioButton
        bg_multiobjective             matlab.ui.container.ButtonGroup
        check_tra                     matlab.ui.control.CheckBox
        ckeck_force                   matlab.ui.control.CheckBox
        bg_forces                     matlab.ui.container.ButtonGroup
        rb_allforces                  matlab.ui.control.RadioButton
        rb_verticalforces             matlab.ui.control.RadioButton
        check_tr                      matlab.ui.control.CheckBox
        bg_tr                         matlab.ui.container.ButtonGroup
        rb_alltr                      matlab.ui.control.RadioButton
        rb_verticaltr                 matlab.ui.control.RadioButton
        bg_singleobjective            matlab.ui.container.ButtonGroup
        rb_singleallforces            matlab.ui.control.RadioButton
        rb_singlealltr                matlab.ui.control.RadioButton
        rb_singleverticalforces       matlab.ui.control.RadioButton
        rb_singleverticaltr           matlab.ui.control.RadioButton
        DesignVariablesPanel          matlab.ui.container.Panel
        LocationCheckBox              matlab.ui.control.CheckBox
        OrientationCheckBox           matlab.ui.control.CheckBox
        MountsPropertiesCheckBox      matlab.ui.control.CheckBox
        tab_OM                        matlab.ui.container.Tab
        panel_con_location            matlab.ui.container.Panel
        sw_mount1loc                  matlab.ui.control.Switch
        Mount1Label                   matlab.ui.control.Label
        UpperBoundLabel               matlab.ui.control.Label
        edit_mount1locup              matlab.ui.control.EditField
        LowerBoundEditFieldLabel      matlab.ui.control.Label
        edit_mount1loclow             matlab.ui.control.EditField
        Mount2Label                   matlab.ui.control.Label
        UpperBoundEditField_2Label    matlab.ui.control.Label
        edit_mount2locup              matlab.ui.control.EditField
        LowerBoundEditField_2Label    matlab.ui.control.Label
        edit_mount2loclow             matlab.ui.control.EditField
        sw_mount2loc                  matlab.ui.control.Switch
        Mount3Label                   matlab.ui.control.Label
        UpperBoundEditField_3Label    matlab.ui.control.Label
        edit_mount3locup              matlab.ui.control.EditField
        LowerBoundEditField_3Label    matlab.ui.control.Label
        edit_mount3loclow             matlab.ui.control.EditField
        sw_mount3loc                  matlab.ui.control.Switch
        Mount4Label                   matlab.ui.control.Label
        sw_mount4loc                  matlab.ui.control.Switch
        UpperBoundEditField_4Label    matlab.ui.control.Label
        edit_mount4locup              matlab.ui.control.EditField
        LowerBoundEditField_4Label    matlab.ui.control.Label
        edit_mount4loclow             matlab.ui.control.EditField
        SymValforxCheckBox            matlab.ui.control.CheckBox
        SymValforyCheckBox            matlab.ui.control.CheckBox
        SymValforzCheckBox            matlab.ui.control.CheckBox
        SymValforxCheckBox_2          matlab.ui.control.CheckBox
        SymValforyCheckBox_2          matlab.ui.control.CheckBox
        SymValforzCheckBox_2          matlab.ui.control.CheckBox
        SymValforxCheckBox_3          matlab.ui.control.CheckBox
        SymValforyCheckBox_3          matlab.ui.control.CheckBox
        SymValforzCheckBox_3          matlab.ui.control.CheckBox
        SymValforxCheckBox_4          matlab.ui.control.CheckBox
        SymValforyCheckBox_4          matlab.ui.control.CheckBox
        SymValforzCheckBox_4          matlab.ui.control.CheckBox
        panel_con_properties          matlab.ui.container.Panel
        panel_con_elastomeric         matlab.ui.container.Panel
        sw_mount1proe                 matlab.ui.control.Switch
        Mount1Label_3                 matlab.ui.control.Label
        UpperBoundLabel_3             matlab.ui.control.Label
        edit_mount1proeup             matlab.ui.control.EditField
        LowerBoundEditFieldLabel_3    matlab.ui.control.Label
        edit_mount1proelow            matlab.ui.control.EditField
        Mount2Label_3                 matlab.ui.control.Label
        sw_mount2proe                 matlab.ui.control.Switch
        UpperBoundEditField_2Label_3  matlab.ui.control.Label
        edit_mount2proeup             matlab.ui.control.EditField
        LowerBoundEditField_2Label_3  matlab.ui.control.Label
        edit_mount2proelow            matlab.ui.control.EditField
        Mount3Label_3                 matlab.ui.control.Label
        sw_mount3proe                 matlab.ui.control.Switch
        UpperBoundEditField_3Label_3  matlab.ui.control.Label
        edit_mount3proeup             matlab.ui.control.EditField
        LowerBoundEditField_3Label_3  matlab.ui.control.Label
        edit_mount3proelow            matlab.ui.control.EditField
        Mount4Label_3                 matlab.ui.control.Label
        sw_mount4proe                 matlab.ui.control.Switch
        UpperBoundEditField_4Label_3  matlab.ui.control.Label
        edit_mount4proeup             matlab.ui.control.EditField
        LowerBoundEditField_4Label_3  matlab.ui.control.Label
        edit_mount4proelow            matlab.ui.control.EditField
        Label14_2                     matlab.ui.control.Label
        panel_con_orientation         matlab.ui.container.Panel
        sw_mount1ori                  matlab.ui.control.Switch
        Mount1Label_2                 matlab.ui.control.Label
        UpperBoundLabel_2             matlab.ui.control.Label
        edit_mount1oriup              matlab.ui.control.EditField
        LowerBoundEditFieldLabel_2    matlab.ui.control.Label
        edit_mount1orilow             matlab.ui.control.EditField
        Mount2Label_2                 matlab.ui.control.Label
        UpperBoundEditField_2Label_2  matlab.ui.control.Label
        edit_mount2oriup              matlab.ui.control.EditField
        LowerBoundEditField_2Label_2  matlab.ui.control.Label
        edit_mount2orilow             matlab.ui.control.EditField
        sw_mount2ori                  matlab.ui.control.Switch
        Mount3Label_2                 matlab.ui.control.Label
        UpperBoundEditField_3Label_2  matlab.ui.control.Label
        edit_mount3oriup              matlab.ui.control.EditField
        LowerBoundEditField_3Label_2  matlab.ui.control.Label
        edit_mount3orilow             matlab.ui.control.EditField
        sw_mount3ori                  matlab.ui.control.Switch
        Mount4Label_2                 matlab.ui.control.Label
        sw_mount4ori                  matlab.ui.control.Switch
        UpperBoundEditField_4Label_2  matlab.ui.control.Label
        edit_mount4oriup              matlab.ui.control.EditField
        LowerBoundEditField_4Label_2  matlab.ui.control.Label
        edit_mount4orilow             matlab.ui.control.EditField
        Label15_2                     matlab.ui.control.Label
        OptimizationTab               matlab.ui.container.Tab
        SettingsPanel                 matlab.ui.container.Panel
        OptimizationMethodDropDownLabel  matlab.ui.control.Label
        OptimizationMethodDropDown    matlab.ui.control.DropDown
        OptimizeButton                matlab.ui.control.Button
        LabelDropDown                 matlab.ui.control.Label
        popup_stage                   matlab.ui.control.DropDown
        SaveButton                    matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % app.shariflogo.Icon = 'shariflogo.png';
            % app.airiclogo.Icon = 'airiclogo.png';
            app.popup_mount4type.Enable = 'off';
            app.edit_mount4position.Enable = 'off';
            app.edit_mount4orientation.Enable = 'off';
            app.edit_mount4stiff.Enable = 'off';
            app.edit_mount4damp.Enable = 'off';
            app.panel_mount4e.ForegroundColor = [0.5, 0.5, 0.5];
            app.bg_multiobjective.Visible = 'off';
            app.bg_singleobjective.Visible = 'off';
            app.edit_mount4locup.Enable = 'Off';
            app.edit_mount4loclow.Enable = 'Off';
            app.edit_mount4oriup.Enable = 'Off';
            app.edit_mount4orilow.Enable = 'Off';
            app.sw_mount4loc.Enable = 'off';
            app.sw_mount4ori.Enable = 'off';
            app.sw_mount4proe.Enable = 'off';
            app.edit_mount4proeup.Enable = 'Off';
            app.edit_mount4proelow.Enable = 'Off';
            app.Mount4Label.FontColor = [0.5, 0.5, 0.5];
            app.Mount4Label_2.FontColor = [0.5, 0.5, 0.5];
            app.Mount4Label_3.FontColor = [0.5, 0.5, 0.5];
            app. SymValforxCheckBox_4.Enable = 'Off';
            app. SymValforyCheckBox_4.Enable = 'Off';
            app. SymValforzCheckBox_4.Enable = 'Off';
        end

        % Value changed function: LocationCheckBox
        function LocationCheckBoxValueChanged(app, event)
            value = app.LocationCheckBox.Value;
            switch value
                case 0
                    app.sw_mount1loc.Enable = 'off';
                    app.edit_mount1locup.Enable = 'Off';
                    app.edit_mount1loclow.Enable = 'Off';
                    app.Mount1Label.FontColor = [0.5, 0.5, 0.5];
                    app.sw_mount2loc.Enable = 'off';
                    app.edit_mount2locup.Enable = 'Off';
                    app.edit_mount2loclow.Enable = 'Off';
                    app.Mount2Label.FontColor = [0.5, 0.5, 0.5];
                    app.sw_mount3loc.Enable = 'off';
                    app.edit_mount3locup.Enable = 'Off';
                    app.edit_mount3loclow.Enable = 'Off';
                    app.Mount3Label.FontColor = [0.5, 0.5, 0.5];
                    app.sw_mount4loc.Enable = 'off';
                    app.edit_mount4locup.Enable = 'Off';
                    app.edit_mount4loclow.Enable = 'Off';
                    app.Mount4Label.FontColor = [0.5, 0.5, 0.5];
                    app. SymValforxCheckBox.Enable = 'Off';
                    app. SymValforyCheckBox.Enable = 'Off';
                    app. SymValforzCheckBox.Enable = 'Off';
                    app. SymValforxCheckBox_2.Enable = 'Off';
                    app. SymValforyCheckBox_2.Enable = 'Off';
                    app. SymValforzCheckBox_2.Enable = 'Off';
                    app. SymValforxCheckBox_3.Enable = 'Off';
                    app. SymValforyCheckBox_3.Enable = 'Off';
                    app. SymValforzCheckBox_3.Enable = 'Off';
                    app. SymValforxCheckBox_4.Enable = 'Off';
                    app. SymValforyCheckBox_4.Enable = 'Off';
                    app. SymValforzCheckBox_4.Enable = 'Off';
                case 1
                    app.sw_mount1loc.Enable = 'on';
                    app.edit_mount1locup.Enable = 'on';
                    app.edit_mount1loclow.Enable = 'on';
                    app.Mount1Label.FontColor = [0, 0, 0];
                    app.sw_mount2loc.Enable = 'on';
                    app.edit_mount2locup.Enable = 'on';
                    app.edit_mount2loclow.Enable = 'on';
                    app.Mount2Label.FontColor = [0, 0, 0];
                    app.sw_mount3loc.Enable = 'on';
                    app.edit_mount3locup.Enable = 'on';
                    app.edit_mount3loclow.Enable = 'on';
                    app.Mount3Label.FontColor = [0, 0, 0];
                    app. SymValforxCheckBox.Enable = 'On';
                    app. SymValforyCheckBox.Enable = 'On';
                    app. SymValforzCheckBox.Enable = 'On';
                    app. SymValforxCheckBox_2.Enable = 'On';
                    app. SymValforyCheckBox_2.Enable = 'On';
                    app. SymValforzCheckBox_2.Enable = 'On';
                    app. SymValforxCheckBox_3.Enable = 'On';
                    app. SymValforyCheckBox_3.Enable = 'On';
                    app. SymValforzCheckBox_3.Enable = 'On';
                    if app.spinner_mountsnumber.Value == 4
                        app.sw_mount4loc.Enable = 'on';
                        app.edit_mount4locup.Enable = 'on';
                        app.edit_mount4loclow.Enable = 'on';
                        app.Mount4Label.FontColor = [0, 0, 0];
                        app. SymValforxCheckBox_4.Enable = 'On';
                        app. SymValforyCheckBox_4.Enable = 'On';
                        app. SymValforzCheckBox_4.Enable = 'On';
                    end
            end
            
        end

        % Value changed function: MountsPropertiesCheckBox
        function MountsPropertiesCheckBoxValueChanged(app, event)
            value = app.MountsPropertiesCheckBox.Value;
            switch value
                case 0
                    app.sw_mount1proe.Enable = 'off';
                    app.edit_mount1proeup.Enable = 'Off';
                    app.edit_mount1proelow.Enable = 'Off';
                    app.Mount1Label_3.FontColor = [0.5, 0.5, 0.5];
                    app.sw_mount2proe.Enable = 'off';
                    app.edit_mount2proeup.Enable = 'Off';
                    app.edit_mount2proelow.Enable = 'Off';
                    app.Mount2Label_3.FontColor = [0.5, 0.5, 0.5];
                    app.sw_mount3proe.Enable = 'off';
                    app.edit_mount3proeup.Enable = 'Off';
                    app.edit_mount3proelow.Enable = 'Off';
                    app.Mount3Label_3.FontColor = [0.5, 0.5, 0.5];
                    app.sw_mount4proe.Enable = 'off';
                    app.edit_mount4proeup.Enable = 'Off';
                    app.edit_mount4proelow.Enable = 'Off';
                    app.Mount4Label_3.FontColor = [0.5, 0.5, 0.5];
                case 1
                    app.sw_mount1proe.Enable = 'on';
                    app.edit_mount1proeup.Enable = 'on';
                    app.edit_mount1proelow.Enable = 'on';
                    app.Mount1Label_3.FontColor = [0, 0, 0];
                    app.sw_mount2proe.Enable = 'on';
                    app.edit_mount2proeup.Enable = 'on';
                    app.edit_mount2proelow.Enable = 'on';
                    app.Mount2Label_3.FontColor = [0, 0, 0];
                    app.sw_mount3proe.Enable = 'on';
                    app.edit_mount3proeup.Enable = 'on';
                    app.edit_mount3proelow.Enable = 'on';
                    app.Mount3Label_3.FontColor = [0, 0, 0];
                    if app.spinner_mountsnumber.Value == 4
                        app.sw_mount4proe.Enable = 'on';
                        app.edit_mount4proeup.Enable = 'on';
                        app.edit_mount4proelow.Enable = 'on';
                        app.Mount4Label_3.FontColor = [0, 0, 0];
                    end
            end
        end

        % Value changed function: OrientationCheckBox
        function OrientationCheckBoxValueChanged(app, event)
            value = app.OrientationCheckBox.Value;
            switch value
                case 0
                    app.sw_mount1ori.Enable = 'off';
                    app.edit_mount1oriup.Enable = 'Off';
                    app.edit_mount1orilow.Enable = 'Off';
                    app.Mount1Label_2.FontColor = [0.5, 0.5, 0.5];
                    app.sw_mount2ori.Enable = 'off';
                    app.edit_mount2oriup.Enable = 'Off';
                    app.edit_mount2orilow.Enable = 'Off';
                    app.Mount2Label_2.FontColor = [0.5, 0.5, 0.5];
                    app.sw_mount3ori.Enable = 'off';
                    app.edit_mount3oriup.Enable = 'Off';
                    app.edit_mount3orilow.Enable = 'Off';
                    app.Mount3Label_2.FontColor = [0.5, 0.5, 0.5];
                    app.sw_mount4ori.Enable = 'off';
                    app.edit_mount4oriup.Enable = 'Off';
                    app.edit_mount4orilow.Enable = 'Off';
                    app.Mount4Label_2.FontColor = [0.5, 0.5, 0.5];
                case 1
                    app.sw_mount1ori.Enable = 'on';
                    app.edit_mount1oriup.Enable = 'on';
                    app.edit_mount1orilow.Enable = 'on';
                    app.Mount1Label_2.FontColor = [0, 0, 0];
                    app.sw_mount2ori.Enable = 'on';
                    app.edit_mount2oriup.Enable = 'on';
                    app.edit_mount2orilow.Enable = 'on';
                    app.Mount2Label_2.FontColor = [0, 0, 0];
                    app.sw_mount3ori.Enable = 'on';
                    app.edit_mount3oriup.Enable = 'on';
                    app.edit_mount3orilow.Enable = 'on';
                    app.Mount3Label_2.FontColor = [0, 0, 0];
                    if app.spinner_mountsnumber.Value == 4
                        app.sw_mount4ori.Enable = 'on';
                        app.edit_mount4oriup.Enable = 'on';
                        app.edit_mount4orilow.Enable = 'on';
                        app.Mount4Label_2.FontColor = [0, 0, 0];
                    end
            end
        end

        % Selection changed function: bg_designstrategy
        function bg_designstrategySelectionChanged(app, event)
            selectedButton = app.bg_designstrategy.SelectedObject;
            switch selectedButton.Text
                case 'TRA Decoupling'
                    app.bg_singleobjective.Visible = 'off';
                    app.bg_multiobjective.Visible = 'off';
                case 'Elastic Axis Decoupling'
                    app.bg_singleobjective.Visible = 'off';
                    app.bg_multiobjective.Visible = 'off';
                case 'Natural Frequency Shifting'
                    app.bg_singleobjective.Visible = 'off';
                    app.bg_multiobjective.Visible = 'off';
                case 'Mode Decoupling and Frequency Placement'
                    app.bg_singleobjective.Visible = 'off';
                    app.bg_multiobjective.Visible = 'off';
                case 'Center of Percussion'
                    app.bg_singleobjective.Visible = 'off';
                    app.bg_multiobjective.Visible = 'off';
                case 'Single-objective Optimization'
                    app.bg_singleobjective.Visible = 'on';
                    app.bg_multiobjective.Visible = 'off';
                case 'Multi-objective Optimization'
                    app.bg_singleobjective.Visible = 'off';
                    app.bg_multiobjective.Visible = 'on';
            end
        end

        % Value changed function: check_tr
        function check_trValueChanged(app, event)
            value = app.check_tr.Value;
            switch value
                case 1
                    app.rb_alltr.Enable = 'on';
                    app.rb_verticaltr.Enable = 'on';
                case 0
                    app.rb_alltr.Enable = 'off';
                    app.rb_verticaltr.Enable = 'off';
            end
        end

        % Value changed function: ckeck_force
        function ckeck_forceValueChanged(app, event)
            value = app.ckeck_force.Value;
            switch value
                case 1
                    app.rb_allforces.Enable = 'on';
                    app.rb_verticalforces.Enable = 'on';
                case 0
                    app.rb_allforces.Enable = 'off';
                    app.rb_verticalforces.Enable = 'off';
            end
        end

        % Value changed function: spinner_mountsnumber
        function spinner_mountsnumberValueChanged(app, event)
            value = app.spinner_mountsnumber.Value;
            switch value
                case 3
                    app.popup_mount4type.Enable = 'off';
                    app.edit_mount4position.Enable = 'off';
                    app.edit_mount4stiff.Enable = 'off';
                    app.edit_mount4damp.Enable = 'off';
                    app.panel_mount4e.ForegroundColor = [0.5, 0.5, 0.5];
                    app.edit_mount4locup.Enable = 'Off';
                    app.edit_mount4loclow.Enable = 'Off';
                    app.edit_mount4oriup.Enable = 'Off';
                    app.edit_mount4orilow.Enable = 'Off';
                    app.sw_mount4loc.Enable = 'off';
                    app.sw_mount4ori.Enable = 'off';
                    app.sw_mount4proe.Enable = 'off';
                    app.edit_mount4proeup.Enable = 'Off';
                    app.edit_mount4proelow.Enable = 'Off';
                    app.Mount4Label.FontColor = [0.5, 0.5, 0.5];
                    app.Mount4Label_2.FontColor = [0.5, 0.5, 0.5];
                    app.Mount4Label_3.FontColor = [0.5, 0.5, 0.5];
                    app. SymValforxCheckBox_4.Enable = 'Off';
                    app. SymValforyCheckBox_4.Enable = 'Off';
                    app. SymValforzCheckBox_4.Enable = 'Off';
                case 4
                    app.popup_mount4type.Enable = 'on';
                    app.edit_mount4position.Enable = 'on';
                    app.edit_mount4orientation.Enable = 'on';
                    app.edit_mount4stiff.Enable = 'on';
                    app.edit_mount4damp.Enable = 'on';
                    app.panel_mount4e.ForegroundColor = [0, 0, 0];
                    if app.LocationCheckBox.Value == 1
                        app.sw_mount4loc.Enable = 'on';
                        app.edit_mount4locup.Enable = 'On';
                        app.edit_mount4loclow.Enable = 'On';
                        app.Mount4Label.FontColor = [0, 0, 0];
                        app. SymValforxCheckBox_4.Enable = 'On';
                        app. SymValforyCheckBox_4.Enable = 'On';
                        app. SymValforzCheckBox_4.Enable = 'On';
                    end
                    if app.OrientationCheckBox.Value == 1
                        app.edit_mount4oriup.Enable = 'On';
                        app.edit_mount4orilow.Enable = 'on';
                        app.sw_mount4ori.Enable = 'on';
                        app.Mount4Label_2.FontColor = [0, 0, 0];
                    end
                    if app.MountsPropertiesCheckBox.Value == 1
                        app.sw_mount4proe.Enable = 'on';
                        app.edit_mount4proeup.Enable = 'On';
                        app.edit_mount4proelow.Enable = 'On';
                        app.Mount4Label_3.FontColor = [0, 0, 0];
                    end
            end
        end

        % Button pushed function: tab_IP
        function save(app, event)
           
           
           I=[str2num(app.edit_Ixx.Value), str2num(app.edit_Ixy.Value),   str2num(app.edit_Ixz.Value);...
              str2num(app.edit_Ixy.Value), str2num(app.edit_Iyy.Value),   str2num(app.edit_Iyz.Value);...
              str2num(app.edit_Ixz.Value), str2num(app.edit_Iyz.Value),   str2num(app.edit_Izz.Value)];


           r={str2num(app.edit_mount1position.Value);   str2num(app.edit_mount2position.Value);   str2num(app.edit_mount3position.Value)};
           o={str2num(app.edit_mount1orientation.Value);...
               str2num(app.edit_mount2orientation.Value);...
               str2num(app.edit_mount3orientation.Value)};
           k_l={str2num(app.edit_mount1stiff.Value);...
               str2num(app.edit_mount2stiff.Value);...
               str2num(app.edit_mount3stiff.Value)};
           c_l={str2num(app.edit_mount1damp.Value);...
               str2num(app.edit_mount2damp.Value);...
               str2num(app.edit_mount3damp.Value)};
           TorqeAmp=str2num(app.TorqueAmp.Value);
           IdleSpeed=str2num(app.IdleSpeed.Value);
           m=str2num(app.edit_mass.Value);
           save('SavedFile','m','I','r','o','k_l','c_l','TorqeAmp','IdleSpeed');
           
           
        end

        % Callback function
        function Run(app, event)
            run('Run.m');
        end

        % Value changed function: OptimizationMethodDropDown
        function OptimizationMethodDropDownValueChanged(app, event)
            
                    
        end

        % Button pushed function: OptimizeButton
        function OptimizeButtonPushed(app, event)
            value = app.OptimizationMethodDropDown.Value
            switch value
                case 'Genetic Algorithm'
                case 'Sequential Quadratic Programming'
                    
                    
                case 'Evelotuionary Strategies'
                case 'Sequential Quadratic Programing'
                    PSO
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'EMS Toolbox';
            setAutoResize(app, app.UIFigure, true)
            app.title = uilabel(app.UIFigure);
            app.title.Text = 'Engine Mounting System Optimization Toolbox';
            app.tab_design = uitabgroup(app.UIFigure);
            app.tab_IP = uitab(app.tab_design);
            app.tab_IP.SizeChangedFcn = createCallbackFcn(app, @save, true);
            app.tab_IP.Title = 'Input Parameters';
            app.tab_IP.Units = 'pixels';
            app.LabelEditField = uilabel(app.tab_IP);
            app.LabelEditField.Text = 'Mass:';
            app.edit_mass = uieditfield(app.tab_IP, 'text');
            app.edit_mass.Value = '150';
            app.Label2 = uilabel(app.tab_IP);
            app.Label2.Text = 'Kg';
            app.InertiaTensor = uipanel(app.tab_IP);
            app.InertiaTensor.Title = 'Inertia Tensor';
            app.edit_Ixx = uieditfield(app.InertiaTensor, 'text');
            app.edit_Ixx.Value = '5.82';
            app.edit_Ixy = uieditfield(app.InertiaTensor, 'text');
            app.edit_Ixy.Value = '-0.82';
            app.edit_Ixz = uieditfield(app.InertiaTensor, 'text');
            app.edit_Ixz.Value = '0.19';
            app.edit_Iyz = uieditfield(app.InertiaTensor, 'text');
            app.edit_Iyz.Value = '-0.21';
            app.edit_Iyy = uieditfield(app.InertiaTensor, 'text');
            app.edit_Iyy.Value = '3.41';
            app.EditField7 = uieditfield(app.InertiaTensor, 'text');
            app.EditField7.Editable = 'off';
            app.EditField7.Value = 'sym.';
            app.edit_Izz = uieditfield(app.InertiaTensor, 'text');
            app.edit_Izz.Value = '5.50';
            app.EditField9 = uieditfield(app.InertiaTensor, 'text');
            app.EditField9.Editable = 'off';
            app.EditField9.Value = 'sym.';
            app.EditField10 = uieditfield(app.InertiaTensor, 'text');
            app.EditField10.Editable = 'off';
            app.EditField10.Value = 'sym.';
            app.panel_mountingsystem = uipanel(app.tab_IP);
            app.panel_mountingsystem.Title = 'Mounting System';
            app.LabelSpinner = uilabel(app.panel_mountingsystem);
            app.LabelSpinner.Text = 'Number of the Mounts:';
            app.spinner_mountsnumber = uispinner(app.panel_mountingsystem);
            app.spinner_mountsnumber.ValueChangedFcn = createCallbackFcn(app, @spinner_mountsnumberValueChanged, true);
            app.spinner_mountsnumber.Limits = [3 4];
            app.spinner_mountsnumber.Value = 3;
            app.panel_mountstype = uipanel(app.panel_mountingsystem);
            app.panel_mountstype.Title = 'Type of the Mounts:';
            app.LabelDropDown2 = uilabel(app.panel_mountstype);
            app.LabelDropDown2.Text = 'Mount #1:';
            app.popup_mount1type = uidropdown(app.panel_mountstype);
            app.popup_mount1type.Items = {'Elastomeric', 'Hydraulic', 'User-defined TF'};
            app.popup_mount1type.Value = 'Elastomeric';
            app.Label4 = uilabel(app.panel_mountstype);
            app.Label4.Text = 'Mount #2:';
            app.popup_mount2type = uidropdown(app.panel_mountstype);
            app.popup_mount2type.Items = {'Elastomeric', 'Hydraulic', 'User-defined TF'};
            app.popup_mount2type.Value = 'Elastomeric';
            app.Label5 = uilabel(app.panel_mountstype);
            app.Label5.Text = 'Mount #3:';
            app.popup_mount3type = uidropdown(app.panel_mountstype);
            app.popup_mount3type.Items = {'Elastomeric', 'Hydraulic', 'User-defined TF'};
            app.popup_mount3type.Value = 'Elastomeric';
            app.Label6 = uilabel(app.panel_mountstype);
            app.Label6.Text = 'Mount #4:';
            app.popup_mount4type = uidropdown(app.panel_mountstype);
            app.popup_mount4type.Items = {'Elastomeric', 'Hydraulic', 'User-defined TF'};
            app.popup_mount4type.Value = 'Elastomeric';
            app.panel_mountsposition = uipanel(app.panel_mountingsystem);
            app.panel_mountsposition.Title = 'Position of the Mounts:';
            app.LabelEditField11 = uilabel(app.panel_mountsposition);
            app.LabelEditField11.Text = 'Mount #1:';
            app.edit_mount1position = uieditfield(app.panel_mountsposition, 'text');
            app.edit_mount1position.Value = '[-93, 417.4, 172.0]';
            app.Label7 = uilabel(app.panel_mountsposition);
            app.Label7.Text = 'Mount #2:';
            app.edit_mount2position = uieditfield(app.panel_mountsposition, 'text');
            app.edit_mount2position.Value = '[-56, -433.1, 116.5]';
            app.Label8 = uilabel(app.panel_mountsposition);
            app.Label8.Text = 'Mount #3:';
            app.edit_mount3position = uieditfield(app.panel_mountsposition, 'text');
            app.edit_mount3position.Value = '[262, 36.9, -68]';
            app.Label9 = uilabel(app.panel_mountsposition);
            app.Label9.Text = 'Mount #4:';
            app.edit_mount4position = uieditfield(app.panel_mountsposition, 'text');
            app.edit_mount4position.Value = '[-240, -45.2, 50]';
            app.panel_mountsorientation = uipanel(app.panel_mountingsystem);
            app.panel_mountsorientation.Title = 'Orientation of the Mounts:';
            app.Label10 = uilabel(app.panel_mountsorientation);
            app.Label10.Text = 'Mount #1:';
            app.edit_mount1orientation = uieditfield(app.panel_mountsorientation, 'text');
            app.edit_mount1orientation.Value = '[180, 10, 180]';
            app.Label11 = uilabel(app.panel_mountsorientation);
            app.Label11.Text = 'Mount #2:';
            app.edit_mount2orientation = uieditfield(app.panel_mountsorientation, 'text');
            app.edit_mount2orientation.Value = '[0, 0, 0]';
            app.Label12 = uilabel(app.panel_mountsorientation);
            app.Label12.Text = 'Mount #3:';
            app.edit_mount3orientation = uieditfield(app.panel_mountsorientation, 'text');
            app.edit_mount3orientation.Value = '[180, 0, 0]';
            app.Label13 = uilabel(app.panel_mountsorientation);
            app.Label13.Text = 'Mount #4:';
            app.edit_mount4orientation = uieditfield(app.panel_mountsorientation, 'text');
            app.edit_mount4orientation.Value = '[0, 0, 0]';
            app.Label14 = uilabel(app.panel_mountingsystem);
            app.Label14.Text = 'mm';
            app.Label15 = uilabel(app.panel_mountingsystem);
            app.Label15.Text = 'deg';
            app.Label3 = uilabel(app.tab_IP);
            app.Label3.Text = 'Kg.m^2';
            app.tab_MP = uitab(app.tab_design);
            app.tab_MP.Title = 'Mounts Properties';
            app.tab_MP.Units = 'pixels';
            app.panel_mount1e = uipanel(app.tab_MP);
            app.panel_mount1e.Title = 'Mount 1 (Elastomeric)';
            app.LabelEditField12 = uilabel(app.panel_mount1e);
            app.LabelEditField12.Text = 'Stiffness:';
            app.edit_mount1stiff = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1stiff.Value = '[94.6, 111.3, 92.4]';
            app.Label17 = uilabel(app.panel_mount1e);
            app.Label17.Text = 'N/mm';
            app.Label16 = uilabel(app.panel_mount1e);
            app.Label16.Text = 'Damping:';
            app.edit_mount1damp = uieditfield(app.panel_mount1e, 'text');
            app.edit_mount1damp.Value = '[946, 1113, 924]';
            app.Label18 = uilabel(app.panel_mount1e);
            app.Label18.Text = 'N.s/m';
            app.panel_mount2e = uipanel(app.tab_MP);
            app.panel_mount2e.Title = 'Mount 2 (Elastomeric)';
            app.Label19 = uilabel(app.panel_mount2e);
            app.Label19.Text = 'Stiffness:';
            app.edit_mount2stiff = uieditfield(app.panel_mount2e, 'text');
            app.edit_mount2stiff.Value = '[72.8, 72.8, 84.4]';
            app.Label20 = uilabel(app.panel_mount2e);
            app.Label20.Text = 'N/mm';
            app.Label21 = uilabel(app.panel_mount2e);
            app.Label21.Text = 'Damping:';
            app.edit_mount2damp = uieditfield(app.panel_mount2e, 'text');
            app.edit_mount2damp.Value = '[728, 728, 844]';
            app.Label22 = uilabel(app.panel_mount2e);
            app.Label22.Text = 'N.s/m';
            app.panel_mount3e = uipanel(app.tab_MP);
            app.panel_mount3e.Title = 'Mount 3 (Elastomeric)';
            app.Label23 = uilabel(app.panel_mount3e);
            app.Label23.Text = 'Stiffness:';
            app.edit_mount3stiff = uieditfield(app.panel_mount3e, 'text');
            app.edit_mount3stiff.Value = '[203.9, 41.7, 82.0]';
            app.Label24 = uilabel(app.panel_mount3e);
            app.Label24.Text = 'N/mm';
            app.Label25 = uilabel(app.panel_mount3e);
            app.Label25.Text = 'Damping:';
            app.edit_mount3damp = uieditfield(app.panel_mount3e, 'text');
            app.edit_mount3damp.Value = '[2039, 417, 820]';
            app.Label26 = uilabel(app.panel_mount3e);
            app.Label26.Text = 'N.s/m';
            app.panel_mount4e = uipanel(app.tab_MP);
            app.panel_mount4e.Title = 'Mount 4 (Elastomeric)';
            app.Label27 = uilabel(app.panel_mount4e);
            app.Label27.Text = 'Stiffness:';
            app.edit_mount4stiff = uieditfield(app.panel_mount4e, 'text');
            app.edit_mount4stiff.Value = '[94.6, 72.8, 82.0]';
            app.Label28 = uilabel(app.panel_mount4e);
            app.Label28.Text = 'N/mm';
            app.Label29 = uilabel(app.panel_mount4e);
            app.Label29.Text = 'Damping:';
            app.edit_mount4damp = uieditfield(app.panel_mount4e, 'text');
            app.edit_mount4damp.Value = '[946, 728, 820]';
            app.Label30 = uilabel(app.panel_mount4e);
            app.Label30.Text = 'N.s/m';
            app.tab_EP = uitab(app.tab_design);
            app.tab_EP.Title = 'Engine Properties';
            app.tab_EP.Units = 'pixels';
            app.Panel = uipanel(app.tab_EP);
            app.Panel.Title = 'Crankshaft';
            app.Label31 = uilabel(app.Panel);
            app.Label31.Text = 'Max. Torque:';
            app.TorqueAmp = uieditfield(app.Panel, 'text');
            app.TorqueAmp.Value = '100';
            app.Label32 = uilabel(app.Panel);
            app.Label32.Text = 'N.m';
            app.Label33 = uilabel(app.Panel);
            app.Label33.Text = 'Idle Speed:';
            app.IdleSpeed = uieditfield(app.Panel, 'text');
            app.IdleSpeed.Value = '800';
            app.Label34 = uilabel(app.Panel);
            app.Label34.Text = 'rpm';
            app.tab_DM = uitab(app.tab_design);
            app.tab_DM.Title = 'Design Method';
            app.tab_DM.Units = 'pixels';
            app.bg_designstrategy = uibuttongroup(app.tab_DM);
            app.bg_designstrategy.SelectionChangedFcn = createCallbackFcn(app, @bg_designstrategySelectionChanged, true);
            app.bg_designstrategy.Title = 'Design Strategy';
            app.RadioButton = uiradiobutton(app.bg_designstrategy);
            app.RadioButton.Text = 'TRA Decoupling';
            app.RadioButton.Value = true;
            app.RadioButton2 = uiradiobutton(app.bg_designstrategy);
            app.RadioButton2.Text = 'Elastic Axis Decoupling';
            app.RadioButton3 = uiradiobutton(app.bg_designstrategy);
            app.RadioButton3.Text = 'Natural Frequency Shifting';
            app.RadioButton4 = uiradiobutton(app.bg_designstrategy);
            app.RadioButton4.Text = 'Mode Decoupling and Frequency Placement';
            app.RadioButton5 = uiradiobutton(app.bg_designstrategy);
            app.RadioButton5.Text = 'Center of Percussion';
            app.RadioButton6 = uiradiobutton(app.bg_designstrategy);
            app.RadioButton6.Text = 'Single-objective Optimization';
            app.RadioButton7 = uiradiobutton(app.bg_designstrategy);
            app.RadioButton7.Text = 'Multi-objective Optimization';
            app.bg_multiobjective = uibuttongroup(app.tab_DM);
            app.bg_multiobjective.Title = 'Objective Functions';
            app.check_tra = uicheckbox(app.bg_multiobjective);
            app.check_tra.Text = 'TRA Decoupling';
            app.ckeck_force = uicheckbox(app.bg_multiobjective);
            app.ckeck_force.ValueChangedFcn = createCallbackFcn(app, @ckeck_forceValueChanged, true);
            app.ckeck_force.Text = 'Norm of the Transmitted Forces through the Mounts';
            app.bg_forces = uibuttongroup(app.bg_multiobjective);
            app.rb_allforces = uiradiobutton(app.bg_forces);
            app.rb_allforces.Enable = 'off';
            app.rb_allforces.Text = 'All Directions';
            app.rb_allforces.Value = true;
            app.rb_verticalforces = uiradiobutton(app.bg_forces);
            app.rb_verticalforces.Enable = 'off';
            app.rb_verticalforces.Text = 'Vertical Direction';
            app.check_tr = uicheckbox(app.bg_multiobjective);
            app.check_tr.ValueChangedFcn = createCallbackFcn(app, @check_trValueChanged, true);
            app.check_tr.Text = 'Force Transmissibility';
            app.bg_tr = uibuttongroup(app.bg_multiobjective);
            app.rb_alltr = uiradiobutton(app.bg_tr);
            app.rb_alltr.Enable = 'off';
            app.rb_alltr.Text = 'All Directions';
            app.rb_alltr.Value = true;
            app.rb_verticaltr = uiradiobutton(app.bg_tr);
            app.rb_verticaltr.Enable = 'off';
            app.rb_verticaltr.Text = 'Vertical Direction';
            app.bg_singleobjective = uibuttongroup(app.tab_DM);
            app.bg_singleobjective.Title = 'Objective Function';
            app.rb_singleallforces = uiradiobutton(app.bg_singleobjective);
            app.rb_singleallforces.Text = 'Norm of the Transmitted Forces through the Mounts (All Directions)';
            app.rb_singleallforces.Value = true;
            app.rb_singlealltr = uiradiobutton(app.bg_singleobjective);
            app.rb_singlealltr.Text = 'Force Transmissibility (All Directions)';
            app.rb_singleverticalforces = uiradiobutton(app.bg_singleobjective);
            app.rb_singleverticalforces.Text = 'Norm of the Transmitted Forces through the Mounts (Vertical Direction)';
            app.rb_singleverticaltr = uiradiobutton(app.bg_singleobjective);
            app.rb_singleverticaltr.Text = 'Force Transmissibility (Vertical Direction)';
            app.DesignVariablesPanel = uipanel(app.tab_DM);
            app.DesignVariablesPanel.Title = 'Design Variables';
            app.LocationCheckBox = uicheckbox(app.DesignVariablesPanel);
            app.LocationCheckBox.ValueChangedFcn = createCallbackFcn(app, @LocationCheckBoxValueChanged, true);
            app.LocationCheckBox.Text = 'Location';
            app.LocationCheckBox.Value = true;
            app.OrientationCheckBox = uicheckbox(app.DesignVariablesPanel);
            app.OrientationCheckBox.ValueChangedFcn = createCallbackFcn(app, @OrientationCheckBoxValueChanged, true);
            app.OrientationCheckBox.Text = 'Orientation';
            app.OrientationCheckBox.Value = true;
            app.MountsPropertiesCheckBox = uicheckbox(app.DesignVariablesPanel);
            app.MountsPropertiesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MountsPropertiesCheckBoxValueChanged, true);
            app.MountsPropertiesCheckBox.Text = 'Mounts Properties';
            app.MountsPropertiesCheckBox.Value = true;
            app.tab_OM = uitab(app.tab_design);
            app.tab_OM.Title = 'Constraints';
            app.tab_OM.Units = 'pixels';
            app.panel_con_location = uipanel(app.tab_OM);
            app.panel_con_location.Title = 'Location';
            app.sw_mount1loc = uiswitch(app.panel_con_location, 'slider');
            app.sw_mount1loc.Items = {'Bounded', 'Fixed'};
            app.sw_mount1loc.Value = 'Bounded';
            app.Mount1Label = uilabel(app.panel_con_location);
            app.Mount1Label.Text = 'Mount 1';
            app.UpperBoundLabel = uilabel(app.panel_con_location);
            app.UpperBoundLabel.Text = 'Upper Bound:';
            app.edit_mount1locup = uieditfield(app.panel_con_location, 'text');
            app.LowerBoundEditFieldLabel = uilabel(app.panel_con_location);
            app.LowerBoundEditFieldLabel.Text = 'Lower Bound:';
            app.edit_mount1loclow = uieditfield(app.panel_con_location, 'text');
            app.Mount2Label = uilabel(app.panel_con_location);
            app.Mount2Label.Text = 'Mount 2';
            app.UpperBoundEditField_2Label = uilabel(app.panel_con_location);
            app.UpperBoundEditField_2Label.Text = 'Upper Bound:';
            app.edit_mount2locup = uieditfield(app.panel_con_location, 'text');
            app.LowerBoundEditField_2Label = uilabel(app.panel_con_location);
            app.LowerBoundEditField_2Label.Text = 'Lower Bound:';
            app.edit_mount2loclow = uieditfield(app.panel_con_location, 'text');
            app.sw_mount2loc = uiswitch(app.panel_con_location, 'slider');
            app.sw_mount2loc.Items = {'Bounded', 'Fixed'};
            app.sw_mount2loc.Value = 'Bounded';
            app.Mount3Label = uilabel(app.panel_con_location);
            app.Mount3Label.Text = 'Mount 3';
            app.UpperBoundEditField_3Label = uilabel(app.panel_con_location);
            app.UpperBoundEditField_3Label.Text = 'Upper Bound:';
            app.edit_mount3locup = uieditfield(app.panel_con_location, 'text');
            app.LowerBoundEditField_3Label = uilabel(app.panel_con_location);
            app.LowerBoundEditField_3Label.Text = 'Lower Bound:';
            app.edit_mount3loclow = uieditfield(app.panel_con_location, 'text');
            app.sw_mount3loc = uiswitch(app.panel_con_location, 'slider');
            app.sw_mount3loc.Items = {'Bounded', 'Fixed'};
            app.sw_mount3loc.Value = 'Bounded';
            app.Mount4Label = uilabel(app.panel_con_location);
            app.Mount4Label.Text = 'Mount 4';
            app.sw_mount4loc = uiswitch(app.panel_con_location, 'slider');
            app.sw_mount4loc.Items = {'Bounded', 'Fixed'};
            app.sw_mount4loc.Value = 'Bounded';
            app.UpperBoundEditField_4Label = uilabel(app.panel_con_location);
            app.UpperBoundEditField_4Label.Text = 'Upper Bound:';
            app.edit_mount4locup = uieditfield(app.panel_con_location, 'text');
            app.LowerBoundEditField_4Label = uilabel(app.panel_con_location);
            app.LowerBoundEditField_4Label.Text = 'Lower Bound:';
            app.edit_mount4loclow = uieditfield(app.panel_con_location, 'text');
            app.SymValforxCheckBox = uicheckbox(app.panel_con_location);
            app.SymValforxCheckBox.Text = 'Sym. Val. for x';
            app.SymValforyCheckBox = uicheckbox(app.panel_con_location);
            app.SymValforyCheckBox.Text = 'Sym. Val. for y';
            app.SymValforzCheckBox = uicheckbox(app.panel_con_location);
            app.SymValforzCheckBox.Text = 'Sym. Val. for z';
            app.SymValforxCheckBox_2 = uicheckbox(app.panel_con_location);
            app.SymValforxCheckBox_2.Text = 'Sym. Val. for x';
            app.SymValforyCheckBox_2 = uicheckbox(app.panel_con_location);
            app.SymValforyCheckBox_2.Text = 'Sym. Val. for y';
            app.SymValforzCheckBox_2 = uicheckbox(app.panel_con_location);
            app.SymValforzCheckBox_2.Text = 'Sym. Val. for z';
            app.SymValforxCheckBox_3 = uicheckbox(app.panel_con_location);
            app.SymValforxCheckBox_3.Text = 'Sym. Val. for x';
            app.SymValforyCheckBox_3 = uicheckbox(app.panel_con_location);
            app.SymValforyCheckBox_3.Text = 'Sym. Val. for y';
            app.SymValforzCheckBox_3 = uicheckbox(app.panel_con_location);
            app.SymValforzCheckBox_3.Text = 'Sym. Val. for z';
            app.SymValforxCheckBox_4 = uicheckbox(app.panel_con_location);
            app.SymValforxCheckBox_4.Text = 'Sym. Val. for x';
            app.SymValforyCheckBox_4 = uicheckbox(app.panel_con_location);
            app.SymValforyCheckBox_4.Text = 'Sym. Val. for y';
            app.SymValforzCheckBox_4 = uicheckbox(app.panel_con_location);
            app.SymValforzCheckBox_4.Text = 'Sym. Val. for z';
            app.panel_con_properties = uipanel(app.tab_OM);
            app.panel_con_properties.Title = 'Mounts Properties';
            app.panel_con_elastomeric = uipanel(app.panel_con_properties);
            app.sw_mount1proe = uiswitch(app.panel_con_elastomeric, 'slider');
            app.sw_mount1proe.Items = {'Bounded', 'Fixed'};
            app.sw_mount1proe.Value = 'Bounded';
            app.Mount1Label_3 = uilabel(app.panel_con_elastomeric);
            app.Mount1Label_3.Text = 'Mount 1';
            app.UpperBoundLabel_3 = uilabel(app.panel_con_elastomeric);
            app.UpperBoundLabel_3.Text = 'Upper Bound:';
            app.edit_mount1proeup = uieditfield(app.panel_con_elastomeric, 'text');
            app.LowerBoundEditFieldLabel_3 = uilabel(app.panel_con_elastomeric);
            app.LowerBoundEditFieldLabel_3.Text = 'Lower Bound:';
            app.edit_mount1proelow = uieditfield(app.panel_con_elastomeric, 'text');
            app.Mount2Label_3 = uilabel(app.panel_con_elastomeric);
            app.Mount2Label_3.Text = 'Mount 2';
            app.sw_mount2proe = uiswitch(app.panel_con_elastomeric, 'slider');
            app.sw_mount2proe.Items = {'Bounded', 'Fixed'};
            app.sw_mount2proe.Value = 'Bounded';
            app.UpperBoundEditField_2Label_3 = uilabel(app.panel_con_elastomeric);
            app.UpperBoundEditField_2Label_3.Text = 'Upper Bound:';
            app.edit_mount2proeup = uieditfield(app.panel_con_elastomeric, 'text');
            app.LowerBoundEditField_2Label_3 = uilabel(app.panel_con_elastomeric);
            app.LowerBoundEditField_2Label_3.Text = 'Lower Bound:';
            app.edit_mount2proelow = uieditfield(app.panel_con_elastomeric, 'text');
            app.Mount3Label_3 = uilabel(app.panel_con_elastomeric);
            app.Mount3Label_3.Text = 'Mount 3';
            app.sw_mount3proe = uiswitch(app.panel_con_elastomeric, 'slider');
            app.sw_mount3proe.Items = {'Bounded', 'Fixed'};
            app.sw_mount3proe.Value = 'Bounded';
            app.UpperBoundEditField_3Label_3 = uilabel(app.panel_con_elastomeric);
            app.UpperBoundEditField_3Label_3.Text = 'Upper Bound:';
            app.edit_mount3proeup = uieditfield(app.panel_con_elastomeric, 'text');
            app.LowerBoundEditField_3Label_3 = uilabel(app.panel_con_elastomeric);
            app.LowerBoundEditField_3Label_3.Text = 'Lower Bound:';
            app.edit_mount3proelow = uieditfield(app.panel_con_elastomeric, 'text');
            app.Mount4Label_3 = uilabel(app.panel_con_elastomeric);
            app.Mount4Label_3.Text = 'Mount 4';
            app.sw_mount4proe = uiswitch(app.panel_con_elastomeric, 'slider');
            app.sw_mount4proe.Items = {'Bounded', 'Fixed'};
            app.sw_mount4proe.Value = 'Bounded';
            app.UpperBoundEditField_4Label_3 = uilabel(app.panel_con_elastomeric);
            app.UpperBoundEditField_4Label_3.Text = 'Upper Bound:';
            app.edit_mount4proeup = uieditfield(app.panel_con_elastomeric, 'text');
            app.LowerBoundEditField_4Label_3 = uilabel(app.panel_con_elastomeric);
            app.LowerBoundEditField_4Label_3.Text = 'Lower Bound:';
            app.edit_mount4proelow = uieditfield(app.panel_con_elastomeric, 'text');
            app.Label14_2 = uilabel(app.tab_OM);
            app.Label14_2.Text = 'mm';
            app.panel_con_orientation = uipanel(app.tab_OM);
            app.panel_con_orientation.Title = 'Orientation';
            app.sw_mount1ori = uiswitch(app.panel_con_orientation, 'slider');
            app.sw_mount1ori.Items = {'Bounded', 'Fixed'};
            app.sw_mount1ori.Value = 'Bounded';
            app.Mount1Label_2 = uilabel(app.panel_con_orientation);
            app.Mount1Label_2.Text = 'Mount 1';
            app.UpperBoundLabel_2 = uilabel(app.panel_con_orientation);
            app.UpperBoundLabel_2.Text = 'Upper Bound:';
            app.edit_mount1oriup = uieditfield(app.panel_con_orientation, 'text');
            app.LowerBoundEditFieldLabel_2 = uilabel(app.panel_con_orientation);
            app.LowerBoundEditFieldLabel_2.Text = 'Lower Bound:';
            app.edit_mount1orilow = uieditfield(app.panel_con_orientation, 'text');
            app.Mount2Label_2 = uilabel(app.panel_con_orientation);
            app.Mount2Label_2.Text = 'Mount 2';
            app.UpperBoundEditField_2Label_2 = uilabel(app.panel_con_orientation);
            app.UpperBoundEditField_2Label_2.Text = 'Upper Bound:';
            app.edit_mount2oriup = uieditfield(app.panel_con_orientation, 'text');
            app.LowerBoundEditField_2Label_2 = uilabel(app.panel_con_orientation);
            app.LowerBoundEditField_2Label_2.Text = 'Lower Bound:';
            app.edit_mount2orilow = uieditfield(app.panel_con_orientation, 'text');
            app.sw_mount2ori = uiswitch(app.panel_con_orientation, 'slider');
            app.sw_mount2ori.Items = {'Bounded', 'Fixed'};
            app.sw_mount2ori.Value = 'Bounded';
            app.Mount3Label_2 = uilabel(app.panel_con_orientation);
            app.Mount3Label_2.Text = 'Mount 3';
            app.UpperBoundEditField_3Label_2 = uilabel(app.panel_con_orientation);
            app.UpperBoundEditField_3Label_2.Text = 'Upper Bound:';
            app.edit_mount3oriup = uieditfield(app.panel_con_orientation, 'text');
            app.LowerBoundEditField_3Label_2 = uilabel(app.panel_con_orientation);
            app.LowerBoundEditField_3Label_2.Text = 'Lower Bound:';
            app.edit_mount3orilow = uieditfield(app.panel_con_orientation, 'text');
            app.sw_mount3ori = uiswitch(app.panel_con_orientation, 'slider');
            app.sw_mount3ori.Items = {'Bounded', 'Fixed'};
            app.sw_mount3ori.Value = 'Bounded';
            app.Mount4Label_2 = uilabel(app.panel_con_orientation);
            app.Mount4Label_2.Text = 'Mount 4';
            app.sw_mount4ori = uiswitch(app.panel_con_orientation, 'slider');
            app.sw_mount4ori.Items = {'Bounded', 'Fixed'};
            app.sw_mount4ori.Value = 'Bounded';
            app.UpperBoundEditField_4Label_2 = uilabel(app.panel_con_orientation);
            app.UpperBoundEditField_4Label_2.Text = 'Upper Bound:';
            app.edit_mount4oriup = uieditfield(app.panel_con_orientation, 'text');
            app.LowerBoundEditField_4Label_2 = uilabel(app.panel_con_orientation);
            app.LowerBoundEditField_4Label_2.Text = 'Lower Bound:';
            app.edit_mount4orilow = uieditfield(app.panel_con_orientation, 'text');
            app.Label15_2 = uilabel(app.tab_OM);
            app.Label15_2.Text = 'deg';
            app.OptimizationTab = uitab(app.tab_design);
            app.OptimizationTab.Title = 'Optimization';
            app.OptimizationTab.Units = 'pixels';
            app.SettingsPanel = uipanel(app.OptimizationTab);
            app.SettingsPanel.Title = 'Settings';
            app.OptimizationMethodDropDownLabel = uilabel(app.OptimizationTab);
            app.OptimizationMethodDropDownLabel.Text = 'Optimization Method:';
            app.OptimizationMethodDropDown = uidropdown(app.OptimizationTab);
            app.OptimizationMethodDropDown.Items = {'Sequential Quadratic Programing', 'Genetic Algorithm', 'Evolutionary Strategies', 'Particle Swarm Optimization'};
            app.OptimizationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @OptimizationMethodDropDownValueChanged, true);
            app.OptimizationMethodDropDown.Value = 'Sequential Quadratic Programing';
            app.OptimizeButton = uibutton(app.OptimizationTab, 'push');
            app.OptimizeButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeButtonPushed, true);
            app.OptimizeButton.Text = 'Optimize';
            app.LabelDropDown = uilabel(app.UIFigure);
            app.LabelDropDown.Text = 'Stage:';
            app.popup_stage = uidropdown(app.UIFigure);
            app.popup_stage.Items = {'Design', 'Results', 'Display'};
            app.popup_stage.Value = 'Design';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @save, true);
            app.SaveButton.Text = 'Save';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.RunButton.Text = 'Run';
        end
    end

    methods (Access = public)

        % Construct app
        function app = NVE_MountEngine_App_960207v1_temp2()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end